classdef Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CheckBox_nodal                 matlab.ui.control.CheckBox
        NodalVoltageButtonGroup        matlab.ui.container.ButtonGroup
        P3                             matlab.ui.control.NumericEditField
        P1                             matlab.ui.control.NumericEditField
        P3puLabel                      matlab.ui.control.Label
        P1puLabel                      matlab.ui.control.Label
        formula                        matlab.ui.control.Label
        X13                            matlab.ui.control.NumericEditField
        X23                            matlab.ui.control.NumericEditField
        X12                            matlab.ui.control.NumericEditField
        Img_nodalgrid                  matlab.ui.control.Image
        ProblemInformationPanel        matlab.ui.container.Panel
        fix_formula                    matlab.ui.control.Label
        info2text                      matlab.ui.control.Label
        info2bold                      matlab.ui.control.Label
        info1bold                      matlab.ui.control.Label
        info1img                       matlab.ui.control.Image
        Img_grid                       matlab.ui.control.Image
        UITable                        matlab.ui.control.Table
        CheckBox_MaxOut                matlab.ui.control.CheckBox
        ConstrainMaxOutputButtonGroup  matlab.ui.container.ButtonGroup
        subscript                      matlab.ui.control.Label
        EditField_MaxPg2               matlab.ui.control.NumericEditField
        EditField_MaxPg1               matlab.ui.control.NumericEditField
        MaxPg2                         matlab.ui.control.RadioButton
        MaxPg1                         matlab.ui.control.RadioButton
        DespachoEconmicoDropDown       matlab.ui.control.DropDown
        DespachoEconmicoDropDownLabel  matlab.ui.control.Label
        PanelResults_AlgorithmStatistics  matlab.ui.container.Panel
        Label_Time                     matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        CostValueLabel                 matlab.ui.control.Label
        Label_vf                       matlab.ui.control.Label
        a_Logo                         matlab.ui.control.Image
        PanelResults_Results           matlab.ui.container.Panel
        Label_teta3                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_teta2                    matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Pg1Label                       matlab.ui.control.Label
        Pg2Label                       matlab.ui.control.Label
        PtotalLabel                    matlab.ui.control.Label
        Label_pg1                      matlab.ui.control.Label
        Label_pg2                      matlab.ui.control.Label
        Label_ptotal                   matlab.ui.control.Label
        FitnessFunctionPanel           matlab.ui.container.Panel
        C11                            matlab.ui.control.NumericEditField
        C12                            matlab.ui.control.NumericEditField
        C21                            matlab.ui.control.NumericEditField
        C22                            matlab.ui.control.NumericEditField
        Placeholder1_4                 matlab.ui.control.Label
        Placeholder1_3                 matlab.ui.control.Label
        Placeholder1_2                 matlab.ui.control.Label
        Placeholder1                   matlab.ui.control.Label
        PghLabel_2                     matlab.ui.control.Label
        PgLabel_2                      matlab.ui.control.Label
        CLabel_2                       matlab.ui.control.Label
        PghLabel                       matlab.ui.control.Label
        PgLabel                        matlab.ui.control.Label
        CLabel                         matlab.ui.control.Label
        AlgorithmOptions               matlab.ui.container.Panel
        ResetTableButton               matlab.ui.control.Button
        Panel_Reproduction             matlab.ui.container.Panel
        CrossoverFractionEditField     matlab.ui.control.NumericEditField
        CrossoverFractionLabel         matlab.ui.control.Label
        EliteCountLabel                matlab.ui.control.Label
        EliteCountEditField            matlab.ui.control.NumericEditField
        ReproductionLabel              matlab.ui.control.Label
        Panel_Mutation                 matlab.ui.container.Panel
        Mutation_EditField1            matlab.ui.control.NumericEditField
        Mutation_EditField2            matlab.ui.control.NumericEditField
        Mutation_TextField2            matlab.ui.control.Label
        Mutation_TextField1            matlab.ui.control.Label
        MutationDropDownLabel          matlab.ui.control.Label
        MutationDropDown               matlab.ui.control.DropDown
        Panel_Crossover                matlab.ui.container.Panel
        RatioLabel                     matlab.ui.control.Label
        EditField_crossover            matlab.ui.control.NumericEditField
        CrossoverDropDownLabel         matlab.ui.control.Label
        CrossoverDropDown              matlab.ui.control.DropDown
        Panel_Selection                matlab.ui.container.Panel
        SizeLabel                      matlab.ui.control.Label
        SelectionDropDownLabel         matlab.ui.control.Label
        SelectionDropDown              matlab.ui.control.DropDown
        EditField_selection            matlab.ui.control.NumericEditField
        Panel_Population               matlab.ui.container.Panel
        PopulationSizeLabel            matlab.ui.control.Label
        InitRangeEditField_2           matlab.ui.control.NumericEditField
        InitPopRangeLabel              matlab.ui.control.Label
        InitRangeEditField             matlab.ui.control.NumericEditField
        PopSizeEditField               matlab.ui.control.NumericEditField
        RunButton                      matlab.ui.control.Button
        AlgorithmOptionsLabel          matlab.ui.control.Label
        Panel_PlotOptions              matlab.ui.container.ButtonGroup
        rangeCheckBox                  matlab.ui.control.CheckBox
        expectationCheckBox            matlab.ui.control.CheckBox
        bestindivCheckBox              matlab.ui.control.CheckBox
        bestfCheckBox                  matlab.ui.control.CheckBox
        maxconstrCheckBox              matlab.ui.control.CheckBox
        selectionCheckBox              matlab.ui.control.CheckBox
        distanceCheckBox               matlab.ui.control.CheckBox
        scoresCheckBox                 matlab.ui.control.CheckBox
        genealogyCheckBox              matlab.ui.control.CheckBox
        stoppingCheckBox               matlab.ui.control.CheckBox
        scorediversityCheckBox         matlab.ui.control.CheckBox
        Panel_Constraints              matlab.ui.container.Panel
        PenaltyFactorEditField         matlab.ui.control.NumericEditField
        PenaltyFactorEditFieldLabel    matlab.ui.control.Label
        InitialPenaltyEditField        matlab.ui.control.NumericEditField
        InitialPenaltyEditFieldLabel   matlab.ui.control.Label
        Panel_StoppingCriteria         matlab.ui.container.Panel
        MaxTimesEditField              matlab.ui.control.NumericEditField
        MaxTimesEditFieldLabel         matlab.ui.control.Label
        MaxGenerationsEditField        matlab.ui.control.NumericEditField
        MaxGenerationsEditFieldLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: a_Logo
        function Image2Clicked(app, event)
            %back to homepage
            home_page
            close(app.UIFigure);
        end

        % Value changed function: DespachoEconmicoDropDown
        function Exercise_Changed(app, event)
            value = app.DespachoEconmicoDropDown.Value;
            %Common Changes
            if value == "Customizar Problema"
              %ON
                app.ProblemInformationPanel.Visible = 0;
                app.Img_grid.Visible = 0;
              %OFF
                app.CheckBox_MaxOut.Visible = 1;
                app.CheckBox_nodal.Visible = 1;
                app.ConstrainMaxOutputButtonGroup.Visible = 1;
                app.FitnessFunctionPanel.Visible = 1;
                app.NodalVoltageButtonGroup.Visible = 1;
            else
              %ON  
                app.ProblemInformationPanel.Visible = 1;
                app.Img_grid.Visible = 1;
              %OFF
                app.CheckBox_MaxOut.Visible = 0;
                app.CheckBox_nodal.Visible = 0;
                app.ConstrainMaxOutputButtonGroup.Visible = 0;
                app.FitnessFunctionPanel.Visible = 0;
                app.NodalVoltageButtonGroup.Visible = 0;
              %Specific Changes
                switch value
                    case "Sem Perdas      (Ex2)"
                        
                        app.info2bold.Text =    'Generator Output Limitation :';
                        app.info2text.Text =    'No Limits';
                        app.fix_formula.Visible = 0;
                    case "Potência Máx.   (Ex3)"
                        
                        app.info2bold.Text =    'Generator Output Limitation :';
                        app.info2text.Text =    'Generator 1 Output ≤  0.5 p.u.';
                        app.fix_formula.Visible = 0;
                    case "Tensões nodais (Ex4)"
                        
                        app.info2bold.Text =    'Nodal Reactance :';
                        app.info2text.Text =   {'    X12 = j0.3';'    X13 = j0.1';'    X23 = j0.1'};
                        app.fix_formula.Visible = 1;
                end      
            end
        end

        % Value changed function: SelectionDropDown
        function Option_SelectionDropDown_Changed(app, event)
            value = app.SelectionDropDown.Value
            
            if value=="selectiontournament"
                app.EditField_selection.Visible = 1;
                app.SizeLabel.Visible =1;
            else
                app.EditField_selection.Visible = 0;            
                app.SizeLabel.Visible =0;
            end
        end

        % Value changed function: CrossoverDropDown
        function Option_CrossoverDropDown_Changed(app, event)
            value = app.CrossoverDropDown.Value;
            if value=="crossoverheuristic"
                app.EditField_crossover.Visible = 1;
                app.RatioLabel.Visible = 1;
            else
                app.EditField_crossover.Visible = 0;            
                app.RatioLabel.Visible = 0;
            end
        end

        % Value changed function: MutationDropDown
        function Option_MutationDropDown_Changed(app, event)
            value = app.MutationDropDown.Value;
            if value=="mutationgaussian"
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 1;
                app.Mutation_TextField2.Visible = 1;
                
                app.Mutation_TextField1.Text = "Scale";
                app.Mutation_EditField1.Value = 1;
                app.Mutation_TextField2.Text = "Shrink";
                app.Mutation_EditField2.Value = 1;
                
            elseif value=="mutationuniform"
                    
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
                
                app.Mutation_TextField1.Text = "Rate";
                app.Mutation_EditField1.Value = 0.01;
            else
                app.Mutation_EditField1.Visible = 0;
                app.Mutation_TextField1.Visible = 0;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
            end
        end

        % Value changed function: CheckBox_nodal
        function ExCustom_Cbox_nodalValueChanged(app, event)
            value = app.CheckBox_nodal.Value;
            if value 
                app.X12.Enable =        1;
                app.X13.Enable =        1;
                app.X23.Enable =        1;
                app.formula.Enable =    1;
            else
                app.X12.Enable =        0;
                app.X13.Enable =        0;
                app.X23.Enable =        0;
                app.formula.Enable =    0;
            end
        end

        % Value changed function: P1, P3, X12, X13, X23
        function ExCustom_Nodal_Changed(app, event)
            x12= app.X12.Value;
            x13= app.X13.Value; 
            x23= app.X23.Value;
            syms teta2 teta3 Pg1 Pg2;
            P1 = app.P1.Value;
            P3 = app.P3.Value;
            P = [   (P1+P3)-Pg2
                    teta2*(1/x12 + 1/x23) - teta3/x23     ];

            Pg = [Pg1 ; Pg2];
            
            app.formula.Text = evalc('pretty(Pg == P)');
        end

        % Value changed function: CheckBox_MaxOut
        function ExCustom_Cbox_MaxOut_Changed(app, event)
            value = app.CheckBox_MaxOut.Value;
            if value
                app.MaxPg1.Enable = 1;
                app.MaxPg2.Enable = 1;                
                if app.MaxPg1.Value 
                app.EditField_MaxPg1.Enable = 1;
                else
                app.EditField_MaxPg1.Enable = 0;
                end
            else
                app.MaxPg1.Enable = 0;
                app.MaxPg2.Enable = 0;
                app.EditField_MaxPg1.Enable = 0;
                app.EditField_MaxPg2.Enable = 0;
            end
            
        end

        % Selection changed function: ConstrainMaxOutputButtonGroup
        function ExCustom_MaxOut_Changed(app, event)
            %texto igual logo uso das Tags
            if app.ConstrainMaxOutputButtonGroup.SelectedObject.Tag == 'Pg1'
                app.EditField_MaxPg1.Enable = 1;
                app.EditField_MaxPg2.Enable = 0;
            else
                app.EditField_MaxPg1.Enable = 0;
                app.EditField_MaxPg2.Enable = 1;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
        % Save Options to cache file 
        if ~isfile('cachefile.mat')
            Exercise='';
            save cachefile.mat;
        end
        % Problem Variables
            % Reset Values
            [P1,P3] = deal(0.4, 0.5);
            [x12,x13,x23] = deal(0.3, 0.1, 0.1);
            [c11,c12,c21,c22] = deal(0.3, 0.01, 0.2, 0.3);
            [pg1_max,pg2_max,NODAL,MAXOUT] = deal(0);
            
            switch app.DespachoEconmicoDropDown.Value
                case 'Sem Perdas      (Ex2)'
                    Exercise = "Ex2";
                case 'Potência Máx.   (Ex3)'
                    Exercise = "Ex3";
                case 'Tensões nodais (Ex4)'
                    Exercise = "Ex4";
                    NODAL=1;
                case 'Customizar Problema'
                    Exercise = "Custom";
                    %Constantes
                        c11=app.C11.Value;      c12=app.C12.Value;
                        c21=app.C21.Value;      c22=app.C22.Value;
                        P1=app.P1.Value;        P3=app.P3.Value;
                    %MAXPG
                    MAXOUT = app.CheckBox_MaxOut.Value;
                    if MAXOUT
                        if app.MaxPg1.Value
                            pg1_max = app.EditField_MaxPg1.Value;
                        else
                            pg2_max = app.EditField_MaxPg1.Value;
                        end
                    end
                    %NODAL
                    NODAL = app.CheckBox_nodal.Value;
                    if NODAL
                        x12=app.X12.Value;
                        x13=app.X13.Value;
                        x23=app.X23.Value;
                    end
            end 

            save('cachefile.mat','Exercise','P1','P3','x12','x13','x23', ...
                        'c11','c12','c21','c22','MAXOUT', ...
                        'pg1_max','pg2_max','NODAL','-append');


        % Algorithm Options
            % Population
                PopSize     =   app.PopSizeEditField.Value;
                InitRange   =  [app.InitRangeEditField.Value
                                app.InitRangeEditField_2.Value];
                save('cachefile.mat', 'PopSize','InitRange','-append');
            % Stopping Criteria
                MaxGen      =   app.MaxGenerationsEditField.Value;
                MaxTime     =   app.MaxTimesEditField.Value;
                save('cachefile.mat', 'MaxGen','MaxTime','-append');
            % Constraints
                InitPen     =   app.InitialPenaltyEditField.Value;
                PenFactor   =   app.PenaltyFactorEditField.Value;
                save('cachefile.mat', 'InitPen','PenFactor','-append');
            % Plots Options            
                plots = [];
                all_options = get(app.Panel_PlotOptions,'Children');
                for i = 1:numel(all_options)
                    if all_options(i).Value == 1
                        plots = [plots ("gaplot" + all_options(i).Text)];
                    end
                end
                save('cachefile.mat', 'plots','-append');
                
            % Selection
                if app.EditField_selection.Visible
                    Selection   = {app.SelectionDropDown.Value app.EditField_selection.Value};
                else
                    Selection   = {app.SelectionDropDown.Value};
                end
                
            % Reproduction
                EliteCount  =   app.EliteCountEditField.Value;
                CrossFract  =   app.CrossoverFractionEditField.Value;
            % Mutationx\x\
                if app.Mutation_EditField2.Visible
                    Mutation = {app.MutationDropDown.Value app.Mutation_EditField1.Value app.Mutation_EditField2.Value};
                elseif app.Mutation_EditField1.Visible
                    Mutation = {app.MutationDropDown.Value app.Mutation_EditField1.Value};                  
                else
                    Mutation = {app.MutationDropDown.Value};
                end
            % Crossover
                Crossover   =   {app.CrossoverDropDown.Value};
            
            save('cachefile.mat', 'Selection','EliteCount', ...
                 'CrossFract','Mutation','Crossover','-append');
            
            % GA Function 
            gacusto();
            
            % Load + Present Results
            load cachefile p1 p2 pt t2 t3 fit time;
            
            app.Label_pg1.Text = p1;
            app.Label_pg2.Text = p2;
            app.Label_ptotal.Text = pt;
            app.Label_teta2.Text = t2;
            app.Label_teta3.Text = t3;
            app.Label_vf.Text = fit;
            app.Label_Time.Text = time;
            
            newData = {char(Exercise), p1, p2, pt, fit, t2, t3, time,...
                        char(erase(Selection(1),'selection')),...
                        num2str(EliteCount,0),...
                        num2str(CrossFract,2),...
                        char(erase(Mutation(1),'mutation')),...
                        char(erase(Crossover(1),'crossover')) };
            save('cachefile.mat','newData','-append');   
            
            app.UITable.Data = [app.UITable.Data; newData];
            
        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)
            app.UITable.Data = {};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AlgorithmOptions = uipanel(app.UIFigure);
            app.Panel_StoppingCriteria = uipanel(app.AlgorithmOptions);
            app.Panel_StoppingCriteria.Title = 'Stopping Criteria';
            app.MaxGenerationsEditFieldLabel = uilabel(app.Panel_StoppingCriteria);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerationsEditField = uieditfield(app.Panel_StoppingCriteria, 'numeric');
            app.MaxGenerationsEditField.Value = 200;
            app.MaxTimesEditFieldLabel = uilabel(app.Panel_StoppingCriteria);
            app.MaxTimesEditFieldLabel.Text = 'Max Time (s)';
            app.MaxTimesEditField = uieditfield(app.Panel_StoppingCriteria, 'numeric');
            app.MaxTimesEditField.LowerLimitInclusive = 'off';
            app.MaxTimesEditField.Limits = [0 Inf];
            app.MaxTimesEditField.Value = 30;
            app.Panel_Constraints = uipanel(app.AlgorithmOptions);
            app.Panel_Constraints.Title = 'Constraints';
            app.InitialPenaltyEditFieldLabel = uilabel(app.Panel_Constraints);
            app.InitialPenaltyEditFieldLabel.Text = 'Initial Penalty ';
            app.InitialPenaltyEditField = uieditfield(app.Panel_Constraints, 'numeric');
            app.InitialPenaltyEditField.Limits = [1 Inf];
            app.InitialPenaltyEditField.Value = 10;
            app.PenaltyFactorEditFieldLabel = uilabel(app.Panel_Constraints);
            app.PenaltyFactorEditFieldLabel.Text = 'Penalty Factor ';
            app.PenaltyFactorEditField = uieditfield(app.Panel_Constraints, 'numeric');
            app.PenaltyFactorEditField.Limits = [1 Inf];
            app.PenaltyFactorEditField.Value = 100;
            app.Panel_PlotOptions = uibuttongroup(app.AlgorithmOptions);
            app.Panel_PlotOptions.Title = 'Plot Options';
            app.scorediversityCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.scorediversityCheckBox.Text = 'scorediversity';
            app.stoppingCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.stoppingCheckBox.Text = 'stopping';
            app.stoppingCheckBox.Value = true;
            app.genealogyCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.genealogyCheckBox.Text = 'genealogy';
            app.scoresCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.scoresCheckBox.Text = 'scores';
            app.distanceCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.distanceCheckBox.Text = 'distance';
            app.selectionCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.selectionCheckBox.Text = 'selection';
            app.maxconstrCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.maxconstrCheckBox.Text = 'maxconstr';
            app.bestfCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.bestfCheckBox.Text = 'bestf';
            app.bestfCheckBox.Value = true;
            app.bestindivCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.bestindivCheckBox.Text = 'bestindiv';
            app.bestindivCheckBox.Value = true;
            app.expectationCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.expectationCheckBox.Text = 'expectation';
            app.expectationCheckBox.Value = true;
            app.rangeCheckBox = uicheckbox(app.Panel_PlotOptions);
            app.rangeCheckBox.Text = 'range';
            app.AlgorithmOptionsLabel = uilabel(app.AlgorithmOptions);
            app.AlgorithmOptionsLabel.Text = 'Algorithm Options';
            app.RunButton = uibutton(app.AlgorithmOptions, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Panel_Population = uipanel(app.AlgorithmOptions);
            app.Panel_Population.Title = 'Population';
            app.PopSizeEditField = uieditfield(app.Panel_Population, 'numeric');
            app.PopSizeEditField.Limits = [1 Inf];
            app.PopSizeEditField.RoundFractionalValues = 'on';
            app.PopSizeEditField.Value = 50;
            app.InitRangeEditField = uieditfield(app.Panel_Population, 'numeric');
            app.InitRangeEditField.Value = -10;
            app.InitPopRangeLabel = uilabel(app.Panel_Population);
            app.InitPopRangeLabel.Text = 'Init. Pop. Range';
            app.InitRangeEditField_2 = uieditfield(app.Panel_Population, 'numeric');
            app.InitRangeEditField_2.Value = 10;
            app.PopulationSizeLabel = uilabel(app.Panel_Population);
            app.PopulationSizeLabel.Text = 'Population Size';
            app.Panel_Selection = uipanel(app.AlgorithmOptions);
            app.EditField_selection = uieditfield(app.Panel_Selection, 'numeric');
            app.EditField_selection.Visible = 'off';
            app.EditField_selection.Value = 4;
            app.SelectionDropDown = uidropdown(app.Panel_Selection);
            app.SelectionDropDown.Items = {'selectionstochunif', 'selectionremainder', 'selectionuniform', 'selectionroulette', 'selectiontournament'};
            app.SelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @Option_SelectionDropDown_Changed, true);
            app.SelectionDropDown.Value = 'selectionstochunif';
            app.SelectionDropDownLabel = uilabel(app.Panel_Selection);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.SizeLabel = uilabel(app.Panel_Selection);
            app.SizeLabel.Visible = 'off';
            app.SizeLabel.Text = 'Size';
            app.Panel_Crossover = uipanel(app.AlgorithmOptions);
            app.CrossoverDropDown = uidropdown(app.Panel_Crossover);
            app.CrossoverDropDown.Items = {'crossoverscattered', 'crossoversinglepoint', 'crossovertwopoint', 'crossoverintermediate', 'crossoverarithmetic', 'crossoverheuristic'};
            app.CrossoverDropDown.ValueChangedFcn = createCallbackFcn(app, @Option_CrossoverDropDown_Changed, true);
            app.CrossoverDropDown.Value = 'crossoverscattered';
            app.CrossoverDropDownLabel = uilabel(app.Panel_Crossover);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.EditField_crossover = uieditfield(app.Panel_Crossover, 'numeric');
            app.EditField_crossover.Visible = 'off';
            app.EditField_crossover.Value = 1.2;
            app.RatioLabel = uilabel(app.Panel_Crossover);
            app.RatioLabel.Visible = 'off';
            app.RatioLabel.Text = 'Ratio';
            app.Panel_Mutation = uipanel(app.AlgorithmOptions);
            app.MutationDropDown = uidropdown(app.Panel_Mutation);
            app.MutationDropDown.Items = {'mutationadaptfeasible', 'mutationpower', 'mutationpositivebasis', 'mutationuniform', 'mutationgaussian'};
            app.MutationDropDown.ValueChangedFcn = createCallbackFcn(app, @Option_MutationDropDown_Changed, true);
            app.MutationDropDown.Value = 'mutationadaptfeasible';
            app.MutationDropDownLabel = uilabel(app.Panel_Mutation);
            app.MutationDropDownLabel.Text = 'Mutation';
            app.Mutation_TextField1 = uilabel(app.Panel_Mutation);
            app.Mutation_TextField1.Visible = 'off';
            app.Mutation_TextField1.Text = 'Scale';
            app.Mutation_TextField2 = uilabel(app.Panel_Mutation);
            app.Mutation_TextField2.Visible = 'off';
            app.Mutation_TextField2.Text = 'Shrink';
            app.Mutation_EditField2 = uieditfield(app.Panel_Mutation, 'numeric');
            app.Mutation_EditField2.Visible = 'off';
            app.Mutation_EditField2.Value = 1;
            app.Mutation_EditField1 = uieditfield(app.Panel_Mutation, 'numeric');
            app.Mutation_EditField1.Visible = 'off';
            app.Mutation_EditField1.Value = 1;
            app.Panel_Reproduction = uipanel(app.AlgorithmOptions);
            app.ReproductionLabel = uilabel(app.Panel_Reproduction);
            app.ReproductionLabel.Text = 'Reproduction';
            app.EliteCountEditField = uieditfield(app.Panel_Reproduction, 'numeric');
            app.EliteCountEditField.Limits = [1 Inf];
            app.EliteCountEditField.RoundFractionalValues = 'on';
            app.EliteCountEditField.Value = 2;
            app.EliteCountLabel = uilabel(app.Panel_Reproduction);
            app.EliteCountLabel.Text = 'Elite Count';
            app.CrossoverFractionLabel = uilabel(app.Panel_Reproduction);
            app.CrossoverFractionLabel.Text = 'Crossover Fraction';
            app.CrossoverFractionEditField = uieditfield(app.Panel_Reproduction, 'numeric');
            app.CrossoverFractionEditField.LowerLimitInclusive = 'off';
            app.CrossoverFractionEditField.UpperLimitInclusive = 'off';
            app.CrossoverFractionEditField.Limits = [0 1];
            app.CrossoverFractionEditField.Value = 0.8;
            app.ResetTableButton = uibutton(app.AlgorithmOptions, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.FitnessFunctionPanel = uipanel(app.UIFigure);
            app.FitnessFunctionPanel.Title = 'Fitness Function';
            app.FitnessFunctionPanel.Visible = 'off';
            app.CLabel = uilabel(app.FitnessFunctionPanel);
            app.CLabel.Text = 'C  =';
            app.PgLabel = uilabel(app.FitnessFunctionPanel);
            app.PgLabel.Text = 'Pg   +';
            app.PghLabel = uilabel(app.FitnessFunctionPanel);
            app.PghLabel.Text = 'Pg   (€ / h)';
            app.CLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.CLabel_2.Text = 'C  =';
            app.PgLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PgLabel_2.Text = 'Pg   +';
            app.PghLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PghLabel_2.Text = 'Pg   (€ / h)';
            app.Placeholder1 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1.Text = '2';
            app.Placeholder1_2 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_2.Text = '2';
            app.Placeholder1_3 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_3.Text = '2                        2                          2';
            app.Placeholder1_4 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_4.Text = '1                        1                          1';
            app.C22 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C22.Value = 0.2;
            app.C21 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C21.Value = 0.2;
            app.C12 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C12.Value = 0.01;
            app.C11 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C11.Value = 0.3;
            app.PanelResults_Results = uipanel(app.UIFigure);
            app.PanelResults_Results.Title = 'Problem Results';
            app.Label_ptotal = uilabel(app.PanelResults_Results);
            app.Label_ptotal.Text = '';
            app.Label_pg2 = uilabel(app.PanelResults_Results);
            app.Label_pg2.Text = '';
            app.Label_pg1 = uilabel(app.PanelResults_Results);
            app.Label_pg1.Text = '';
            app.PtotalLabel = uilabel(app.PanelResults_Results);
            app.PtotalLabel.Text = 'Ptotal';
            app.Pg2Label = uilabel(app.PanelResults_Results);
            app.Pg2Label.Text = 'Pg2';
            app.Pg1Label = uilabel(app.PanelResults_Results);
            app.Pg1Label.Text = 'Pg1';
            app.Label_2 = uilabel(app.PanelResults_Results);
            app.Label_2.Text = 'Θ2';
            app.Label_teta2 = uilabel(app.PanelResults_Results);
            app.Label_teta2.Text = '';
            app.Label_3 = uilabel(app.PanelResults_Results);
            app.Label_3.Text = 'Θ3';
            app.Label_teta3 = uilabel(app.PanelResults_Results);
            app.Label_teta3.Text = '';
            app.a_Logo = uiimage(app.UIFigure);
            app.a_Logo.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.a_Logo.ImageSource = 'isep.png';
            app.PanelResults_AlgorithmStatistics = uipanel(app.UIFigure);
            app.PanelResults_AlgorithmStatistics.Title = 'Algorithm Statistics';
            app.Label_vf = uilabel(app.PanelResults_AlgorithmStatistics);
            app.Label_vf.Text = '';
            app.CostValueLabel = uilabel(app.PanelResults_AlgorithmStatistics);
            app.CostValueLabel.Text = 'Cost Value:';
            app.TimeLabel = uilabel(app.PanelResults_AlgorithmStatistics);
            app.TimeLabel.Text = 'Time:';
            app.Label_Time = uilabel(app.PanelResults_AlgorithmStatistics);
            app.Label_Time.Text = '';
            app.DespachoEconmicoDropDownLabel = uilabel(app.UIFigure);
            app.DespachoEconmicoDropDownLabel.Text = 'Despacho Económico';
            app.DespachoEconmicoDropDown = uidropdown(app.UIFigure);
            app.DespachoEconmicoDropDown.Items = {'Sem Perdas      (Ex2)', 'Potência Máx.   (Ex3)', 'Tensões nodais (Ex4)', 'Customizar Problema'};
            app.DespachoEconmicoDropDown.ValueChangedFcn = createCallbackFcn(app, @Exercise_Changed, true);
            app.DespachoEconmicoDropDown.Value = 'Sem Perdas      (Ex2)';
            app.ConstrainMaxOutputButtonGroup = uibuttongroup(app.UIFigure);
            app.ConstrainMaxOutputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExCustom_MaxOut_Changed, true);
            app.ConstrainMaxOutputButtonGroup.Title = 'Constrain Max Output';
            app.ConstrainMaxOutputButtonGroup.Visible = 'off';
            app.MaxPg1 = uiradiobutton(app.ConstrainMaxOutputButtonGroup);
            app.MaxPg1.Tag = 'Pg1';
            app.MaxPg1.Enable = 'off';
            app.MaxPg1.Text = 'Pg    ≤                   p.u.';
            app.MaxPg1.Value = true;
            app.MaxPg2 = uiradiobutton(app.ConstrainMaxOutputButtonGroup);
            app.MaxPg2.Tag = 'Pg2';
            app.MaxPg2.Enable = 'off';
            app.MaxPg2.Text = 'Pg    ≤                   p.u.';
            app.EditField_MaxPg1 = uieditfield(app.ConstrainMaxOutputButtonGroup, 'numeric');
            app.EditField_MaxPg1.LowerLimitInclusive = 'off';
            app.EditField_MaxPg1.Limits = [0 Inf];
            app.EditField_MaxPg1.Enable = 'off';
            app.EditField_MaxPg1.Value = 0.5;
            app.EditField_MaxPg2 = uieditfield(app.ConstrainMaxOutputButtonGroup, 'numeric');
            app.EditField_MaxPg2.LowerLimitInclusive = 'off';
            app.EditField_MaxPg2.Limits = [0 Inf];
            app.EditField_MaxPg2.Enable = 'off';
            app.EditField_MaxPg2.Value = 0.5;
            app.subscript = uilabel(app.ConstrainMaxOutputButtonGroup);
            app.subscript.Enable = 'off';
            app.subscript.Text = {'1'; ''; ''; '2'};
            app.CheckBox_MaxOut = uicheckbox(app.UIFigure);
            app.CheckBox_MaxOut.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Cbox_MaxOut_Changed, true);
            app.CheckBox_MaxOut.Visible = 'off';
            app.CheckBox_MaxOut.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Mode'; 'Pg1 (p.u.)'; 'Pg2 (p.u.)'; 'Ptotal (p.u.)'; 'Fitness Value'; 'Θ2'; 'Θ3'; 'Run Time (s)'; 'SelectionFnc'; 'EliteCount'; 'CrossFract'; 'Mutation'; 'Crossover'};
            app.UITable.RowName = {};
            app.Img_grid = uiimage(app.UIFigure);
            app.Img_grid.ImageSource = 'grid-removebg.png';
            app.ProblemInformationPanel = uipanel(app.UIFigure);
            app.ProblemInformationPanel.Title = 'Problem Information';
            app.info1img = uiimage(app.ProblemInformationPanel);
            app.info1img.ImageSource = 'img_func.png';
            app.info1bold = uilabel(app.ProblemInformationPanel);
            app.info1bold.Text = 'Production Cost Function:';
            app.info2bold = uilabel(app.ProblemInformationPanel);
            app.info2bold.Text = 'Generator Output Limitation :';
            app.info2text = uilabel(app.ProblemInformationPanel);
            app.info2text.Text = 'No Limits';
            app.fix_formula = uilabel(app.ProblemInformationPanel);
            app.fix_formula.Visible = 'off';
            app.fix_formula.Text = {'/        40 teta2            \'; '| Pg2 == -------- - 10 teta3 |'; '|            3               |'; '|                            |'; '|                            |'; '\      Pg1 == 0.9 - Pg2      /'};
            app.NodalVoltageButtonGroup = uibuttongroup(app.UIFigure);
            app.NodalVoltageButtonGroup.Title = 'Nodal Voltage';
            app.NodalVoltageButtonGroup.Visible = 'off';
            app.Img_nodalgrid = uiimage(app.NodalVoltageButtonGroup);
            app.Img_nodalgrid.ImageSource = 'grid-removebg.png';
            app.X12 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.X12.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Nodal_Changed, true);
            app.X12.Enable = 'off';
            app.X12.Value = 0.3;
            app.X23 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.X23.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Nodal_Changed, true);
            app.X23.Enable = 'off';
            app.X23.Value = 0.1;
            app.X13 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.X13.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Nodal_Changed, true);
            app.X13.Enable = 'off';
            app.X13.Value = 0.1;
            app.formula = uilabel(app.NodalVoltageButtonGroup);
            app.formula.Enable = 'off';
            app.formula.Text = {'/      Pg1 == 0.9 - Pg2      \'; '|                            |'; '|        40 teta2            |'; '| Pg2 == -------- - 10 teta3 |'; '\            3               /'};
            app.P1puLabel = uilabel(app.NodalVoltageButtonGroup);
            app.P1puLabel.Text = 'P1 =            p.u.';
            app.P3puLabel = uilabel(app.NodalVoltageButtonGroup);
            app.P3puLabel.Text = 'P3 =            p.u.';
            app.P1 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.P1.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Nodal_Changed, true);
            app.P1.Value = 0.4;
            app.P3 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.P3.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Nodal_Changed, true);
            app.P3.Value = 0.5;
            app.CheckBox_nodal = uicheckbox(app.UIFigure);
            app.CheckBox_nodal.ValueChangedFcn = createCallbackFcn(app, @ExCustom_Cbox_nodalValueChanged, true);
            app.CheckBox_nodal.Visible = 'off';
            app.CheckBox_nodal.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end