classdef ex2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ButtonGroup                  matlab.ui.container.ButtonGroup
        Button3                      matlab.ui.control.ToggleButton
        Button2                      matlab.ui.control.ToggleButton
        Button                       matlab.ui.control.ToggleButton
        PopulationPanel              matlab.ui.container.Panel
        PopulationSizeLabel          matlab.ui.control.Label
        InitRangeEditField_2         matlab.ui.control.NumericEditField
        InitPopRangeLabel            matlab.ui.control.Label
        InitRangeEditField           matlab.ui.control.NumericEditField
        PopSizeEditField             matlab.ui.control.NumericEditField
        EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel               matlab.ui.control.Label
        ResultadosPanel              matlab.ui.container.Panel
        ValorfitnessLabel            matlab.ui.control.Label
        Pg1Label                     matlab.ui.control.Label
        Pg2Label                     matlab.ui.control.Label
        PtotalLabel                  matlab.ui.control.Label
        Label_vf                     matlab.ui.control.Label
        Label_pg1                    matlab.ui.control.Label
        Label_pg2                    matlab.ui.control.Label
        Label_ptotal                 matlab.ui.control.Label
        FitnessFunctionPanel         matlab.ui.container.Panel
        Placeholder1_7               matlab.ui.control.Label
        Placeholder1_6               matlab.ui.control.Label
        Placeholder1_5               matlab.ui.control.Label
        Placeholder1_4               matlab.ui.control.Label
        PhLabel_2                    matlab.ui.control.Label
        C22                          matlab.ui.control.NumericEditField
        PLabel                       matlab.ui.control.Label
        C21                          matlab.ui.control.NumericEditField
        CLabel_2                     matlab.ui.control.Label
        PhLabel                      matlab.ui.control.Label
        C12                          matlab.ui.control.NumericEditField
        PLabel_2                     matlab.ui.control.Label
        C11                          matlab.ui.control.NumericEditField
        CLabel                       matlab.ui.control.Label
        MutationDropDownLabel        matlab.ui.control.Label
        MutationDropDown             matlab.ui.control.DropDown
        CrossoverDropDownLabel       matlab.ui.control.Label
        CrossoverDropDown            matlab.ui.control.DropDown
        SelectionDropDownLabel       matlab.ui.control.Label
        SelectionDropDown            matlab.ui.control.DropDown
        Image                        matlab.ui.control.Image
        RunButton                    matlab.ui.control.Button
        FunoFitnessLabel             matlab.ui.control.Label
        GeradoresLabel               matlab.ui.control.Label
        EditField_P3                 matlab.ui.control.EditField
        ButtonGroup_Plot             matlab.ui.container.ButtonGroup
        rangeCheckBox                matlab.ui.control.CheckBox
        expectationCheckBox          matlab.ui.control.CheckBox
        bestindivCheckBox            matlab.ui.control.CheckBox
        bestfCheckBox                matlab.ui.control.CheckBox
        maxconstrCheckBox            matlab.ui.control.CheckBox
        selectionCheckBox            matlab.ui.control.CheckBox
        distanceCheckBox             matlab.ui.control.CheckBox
        scoresCheckBox               matlab.ui.control.CheckBox
        genealogyCheckBox            matlab.ui.control.CheckBox
        stoppingCheckBox             matlab.ui.control.CheckBox
        scorediversityCheckBox       matlab.ui.control.CheckBox
        EditField_selection          matlab.ui.control.NumericEditField
        EditField_crossover          matlab.ui.control.NumericEditField
        ConstraintsPanel             matlab.ui.container.Panel
        PenaltyFactorEditField       matlab.ui.control.NumericEditField
        PenaltyFactorEditFieldLabel  matlab.ui.control.Label
        InitialPenaltyEditField      matlab.ui.control.NumericEditField
        InitialPenaltyLabel          matlab.ui.control.Label
        StoppingCriteriaPanel        matlab.ui.container.Panel
        MaxTimesEditField            matlab.ui.control.NumericEditField
        MaxTimesEditFieldLabel       matlab.ui.control.Label
        MaxGenEditField              matlab.ui.control.NumericEditField
        MaxGenEditFieldLabel         matlab.ui.control.Label
        Mutation_EditField1          matlab.ui.control.NumericEditField
        Mutation_EditField2          matlab.ui.control.NumericEditField
        Mutation_TextField2          matlab.ui.control.Label
        Mutation_TextField1          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        % Get Plots            
            plots = [];
            all_options = get(app.ButtonGroup_Plot,'Children')
            for i = 1:numel(all_options)
                if all_options(i).Value == 1
                    plots = [plots ("@gaplot" + all_options(i).Text)]
                end
            end
            save('cachefile.mat', 'plots','-append'); 
            
            % Constantes 
            pg1     = str2double(app.C11.Value);
            pg1_2   = str2double(app.C12.Value);
            pg2     = str2double(app.C21.Value);
            pg2_2   = str2double(app.C22.Value);
            
            p3      = str2double(app.EditField_P3.Value);
            
            %save cachefile pg1 pg1_2 pg2 pg2_2 p3;
            save('cachefile.mat', 'pg1','pg1_2','pg2','pg2_2','p3','-append'); 
            
            %chama a função gacusto
            gacusto();
            load cachefile x1 x2 x3 x4;
            
            % atualiza os resultados
            app.Label_pg1.Text     = x1;
            app.Label_pg2.Text     = x2;
            app.Label_vf.Text      = x3;
            app.Label_ptotal.Text  = x4;
        
        end

        % Callback function
        function EscolhaButtonPushed(app, event)
            escolha
            close(app.UIFigure)
        end

        % Value changed function: SelectionDropDown
        function SelectionDropDownValueChanged(app, event)
            value = app.SelectionDropDown.Value
            
            if value=="selectiontournament"
                app.EditField_selection.Visible = 1;
            else
                app.EditField_selection.Visible = 0;            
            end
        end

        % Value changed function: CrossoverDropDown
        function CrossoverDropDownValueChanged(app, event)
            value = app.CrossoverDropDown.Value;
            if value=="crossoverheuristic"
                app.EditField_crossover.Visible = 1;
            else
                app.EditField_crossover.Visible = 0;            
            end
        end

        % Value changed function: MutationDropDown
        function MutationDropDownValueChanged(app, event)
            value = app.MutationDropDown.Value;
            if value=="mutationgaussian"
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 1;
                app.Mutation_TextField2.Visible = 1;
                
                app.Mutation_TextField1.Text = "Scale";
                app.Mutation_EditField1.Value = 1;
                app.Mutation_TextField2.Text = "Shrink";
                app.Mutation_EditField2.Value = 1;
                
            elseif value=="mutationuniform"
                    
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
                
                app.Mutation_TextField1.Text = "Rate";
                app.Mutation_EditField1.Value = 0.01;
            else
                app.Mutation_EditField1.Visible = 0;
                app.Mutation_TextField1.Visible = 0;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Mutation_TextField1 = uilabel(app.UIFigure);
            app.Mutation_TextField1.Text = 'Scale';
            app.Mutation_TextField2 = uilabel(app.UIFigure);
            app.Mutation_TextField2.Text = 'Shrink';
            app.Mutation_EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.Mutation_EditField2.Value = 1;
            app.Mutation_EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.Mutation_EditField1.Value = 1;
            app.StoppingCriteriaPanel = uipanel(app.UIFigure);
            app.StoppingCriteriaPanel.Title = 'Stopping Criteria';
            app.MaxGenEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxGenEditFieldLabel.Text = 'Max. Gen.';
            app.MaxGenEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.MaxGenEditField.Value = 200;
            app.MaxTimesEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxTimesEditFieldLabel.Text = 'Max Time (s)';
            app.MaxTimesEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.ConstraintsPanel = uipanel(app.UIFigure);
            app.ConstraintsPanel.Title = 'Constraints';
            app.InitialPenaltyLabel = uilabel(app.ConstraintsPanel);
            app.InitialPenaltyLabel.Text = 'Initial Penalty ';
            app.InitialPenaltyEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.InitialPenaltyEditField.Limits = [1 Inf];
            app.InitialPenaltyEditField.Value = 10;
            app.PenaltyFactorEditFieldLabel = uilabel(app.ConstraintsPanel);
            app.PenaltyFactorEditFieldLabel.Text = 'Penalty Factor ';
            app.PenaltyFactorEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.PenaltyFactorEditField.Limits = [1 Inf];
            app.PenaltyFactorEditField.Value = 100;
            app.EditField_crossover = uieditfield(app.UIFigure, 'numeric');
            app.EditField_crossover.Visible = 'off';
            app.EditField_crossover.Value = 1.2;
            app.EditField_selection = uieditfield(app.UIFigure, 'numeric');
            app.EditField_selection.Visible = 'off';
            app.EditField_selection.Value = 4;
            app.ButtonGroup_Plot = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Plot.Title = 'Plot Options';
            app.scorediversityCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scorediversityCheckBox.Text = 'scorediversity';
            app.stoppingCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.stoppingCheckBox.Text = 'stopping';
            app.stoppingCheckBox.Value = true;
            app.genealogyCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.genealogyCheckBox.Text = 'genealogy';
            app.scoresCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scoresCheckBox.Text = 'scores';
            app.distanceCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.distanceCheckBox.Text = 'distance';
            app.selectionCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.selectionCheckBox.Text = 'selection';
            app.maxconstrCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.maxconstrCheckBox.Text = 'maxconstr';
            app.bestfCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestfCheckBox.Text = 'bestf';
            app.bestfCheckBox.Value = true;
            app.bestindivCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestindivCheckBox.Text = 'bestindiv';
            app.bestindivCheckBox.Value = true;
            app.expectationCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.expectationCheckBox.Text = 'expectation';
            app.expectationCheckBox.Value = true;
            app.rangeCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.rangeCheckBox.Text = 'range';
            app.EditField_P3 = uieditfield(app.UIFigure, 'text');
            app.EditField_P3.Value = '0.5';
            app.GeradoresLabel = uilabel(app.UIFigure);
            app.GeradoresLabel.Text = 'Geradores';
            app.FunoFitnessLabel = uilabel(app.UIFigure);
            app.FunoFitnessLabel.Text = 'Função Fitness';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'img_1.png';
            app.SelectionDropDown = uidropdown(app.UIFigure);
            app.SelectionDropDown.Items = {'selectionstochunif', 'selectionremainder', 'selectionuniform', 'selectionroulette', 'selectiontournament'};
            app.SelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionDropDownValueChanged, true);
            app.SelectionDropDown.Value = 'selectionstochunif';
            app.SelectionDropDownLabel = uilabel(app.UIFigure);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.CrossoverDropDown = uidropdown(app.UIFigure);
            app.CrossoverDropDown.Items = {'crossoverscattered', 'crossoversinglepoint', 'crossovertwopoint', 'crossoverintermediate', 'crossoverintermediate', 'crossoverheuristic', 'crossoverarithmetic'};
            app.CrossoverDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossoverDropDownValueChanged, true);
            app.CrossoverDropDown.Value = 'crossoverscattered';
            app.CrossoverDropDownLabel = uilabel(app.UIFigure);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.MutationDropDown = uidropdown(app.UIFigure);
            app.MutationDropDown.Items = {'mutationgaussian', 'mutationuniform', 'mutationadaptfeasible', 'mutationpower', 'mutationpositivebasis'};
            app.MutationDropDown.ValueChangedFcn = createCallbackFcn(app, @MutationDropDownValueChanged, true);
            app.MutationDropDown.Value = 'mutationgaussian';
            app.MutationDropDownLabel = uilabel(app.UIFigure);
            app.MutationDropDownLabel.Text = 'Mutation';
            app.FitnessFunctionPanel = uipanel(app.UIFigure);
            app.FitnessFunctionPanel.Title = 'Fitness Function';
            app.CLabel = uilabel(app.FitnessFunctionPanel);
            app.CLabel.Text = 'C  =';
            app.C11 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C11.Value = 0.3;
            app.PLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PLabel_2.Text = 'P     +';
            app.C12 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C12.Value = 0.01;
            app.PhLabel = uilabel(app.FitnessFunctionPanel);
            app.PhLabel.Text = 'P     (€ / h)';
            app.CLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.CLabel_2.Text = 'C  =';
            app.C21 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C21.Value = 0.2;
            app.PLabel = uilabel(app.FitnessFunctionPanel);
            app.PLabel.Text = 'P     +';
            app.C22 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C22.Value = 0.2;
            app.PhLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PhLabel_2.Text = 'P     (€ / h)';
            app.Placeholder1_4 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_4.Text = '2';
            app.Placeholder1_5 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_5.Text = '2';
            app.Placeholder1_6 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_6.Text = '2                    G2                       G2';
            app.Placeholder1_7 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_7.Text = '1                    G1                       G1';
            app.ResultadosPanel = uipanel(app.UIFigure);
            app.ResultadosPanel.Title = 'Resultados';
            app.Label_ptotal = uilabel(app.ResultadosPanel);
            app.Label_ptotal.Text = '';
            app.Label_pg2 = uilabel(app.ResultadosPanel);
            app.Label_pg2.Text = '';
            app.Label_pg1 = uilabel(app.ResultadosPanel);
            app.Label_pg1.Text = '';
            app.Label_vf = uilabel(app.ResultadosPanel);
            app.Label_vf.Text = '';
            app.PtotalLabel = uilabel(app.ResultadosPanel);
            app.PtotalLabel.Text = 'Ptotal:';
            app.Pg2Label = uilabel(app.ResultadosPanel);
            app.Pg2Label.Text = 'Pg2:';
            app.Pg1Label = uilabel(app.ResultadosPanel);
            app.Pg1Label.Text = 'Pg1:';
            app.ValorfitnessLabel = uilabel(app.ResultadosPanel);
            app.ValorfitnessLabel.Text = 'Valor fitness:';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.PopulationPanel = uipanel(app.UIFigure);
            app.PopulationPanel.Title = 'Population';
            app.PopSizeEditField = uieditfield(app.PopulationPanel, 'numeric');
            app.PopSizeEditField.Limits = [1 Inf];
            app.PopSizeEditField.RoundFractionalValues = 'on';
            app.PopSizeEditField.Value = 50;
            app.InitRangeEditField = uieditfield(app.PopulationPanel, 'numeric');
            app.InitRangeEditField.Value = -10;
            app.InitPopRangeLabel = uilabel(app.PopulationPanel);
            app.InitPopRangeLabel.Text = 'Init. Pop. Range';
            app.InitRangeEditField_2 = uieditfield(app.PopulationPanel, 'numeric');
            app.InitRangeEditField_2.Value = 10;
            app.PopulationSizeLabel = uilabel(app.PopulationPanel);
            app.PopulationSizeLabel.Text = 'Population Size';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uitogglebutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uitogglebutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end