classdef ex3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        P3Label           matlab.ui.control.Label
        P3EditField       matlab.ui.control.NumericEditField
        P3EditFieldLabel  matlab.ui.control.Label
        Image             matlab.ui.control.Image
        P1Label           matlab.ui.control.Label
        P1EditField       matlab.ui.control.NumericEditField
        P1EditFieldLabel  matlab.ui.control.Label
        RETURNButton      matlab.ui.control.Button
        RUNButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            a = str2double(app.P1Label.Text);
            b = str2double(app.P3Label.Text);
            gacusto3(a,b);
        end

        % Button pushed function: RETURNButton
        function RETURNButtonPushed(app, event)
           escolha
           close(app.UIFigure);
        end

        % Value changed function: P1EditField
        function P1EditFieldValueChanged(app, event)
            value = app.P1EditField.Value;
            textValue1 = string(value);
            app.P1Label.Text = textValue1;
        end

        % Value changed function: P3EditField
        function P3EditFieldValueChanged(app, event)
            value = app.P3EditField.Value;
            textValue3 = string(value);
            app.P3Label.Text = textValue3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.RETURNButton = uibutton(app.UIFigure, 'push');
            app.RETURNButton.ButtonPushedFcn = createCallbackFcn(app, @RETURNButtonPushed, true);
            app.RETURNButton.Text = 'RETURN';
            app.P1EditFieldLabel = uilabel(app.UIFigure);
            app.P1EditFieldLabel.Text = 'P1';
            app.P1EditField = uieditfield(app.UIFigure, 'numeric');
            app.P1EditField.ValueChangedFcn = createCallbackFcn(app, @P1EditFieldValueChanged, true);
            app.P1Label = uilabel(app.UIFigure);
            app.P1Label.Text = 'P1';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'img_geradores.png';
            app.P3EditFieldLabel = uilabel(app.UIFigure);
            app.P3EditFieldLabel.Text = 'P3';
            app.P3EditField = uieditfield(app.UIFigure, 'numeric');
            app.P3EditField.ValueChangedFcn = createCallbackFcn(app, @P3EditFieldValueChanged, true);
            app.P3Label = uilabel(app.UIFigure);
            app.P3Label.Text = 'P3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end