classdef excustom < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        teta3teta2x12x23x2311teta2teta3x13x23x23Label  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ConstrainMaxOutputButtonGroup  matlab.ui.container.ButtonGroup
        Placeholder1_9                 matlab.ui.control.Label
        CheckBox                       matlab.ui.control.CheckBox
        EditField_2                    matlab.ui.control.NumericEditField
        PgEditField                    matlab.ui.control.NumericEditField
        Pg2                            matlab.ui.control.RadioButton
        Pg1                            matlab.ui.control.RadioButton
        DespachoEconmicoDropDown       matlab.ui.control.DropDown
        DespachoEconmicoDropDownLabel  matlab.ui.control.Label
        PanelResults_AlgorithmStatistics  matlab.ui.container.Panel
        Label_vf_2                     matlab.ui.control.Label
        TimeLabel                      matlab.ui.control.Label
        FitnessValueLabel              matlab.ui.control.Label
        Label_vf                       matlab.ui.control.Label
        ProblemInformationPanel        matlab.ui.container.Panel
        info2text                      matlab.ui.control.Label
        info2bold                      matlab.ui.control.Label
        info1bold                      matlab.ui.control.Label
        info1img                       matlab.ui.control.Image
        Panel                          matlab.ui.container.Panel
        MaxGenEditField                matlab.ui.control.NumericEditField
        CrossoverFractionLabel         matlab.ui.control.Label
        EliteCountLabel                matlab.ui.control.Label
        EliteCountEditField            matlab.ui.control.NumericEditField
        ReproductionLabel              matlab.ui.control.Label
        a_Logo                         matlab.ui.control.Image
        PanelOption_Mutation           matlab.ui.container.Panel
        Mutation_EditField1            matlab.ui.control.NumericEditField
        Mutation_EditField2            matlab.ui.control.NumericEditField
        Mutation_TextField2            matlab.ui.control.Label
        Mutation_TextField1            matlab.ui.control.Label
        MutationDropDownLabel          matlab.ui.control.Label
        MutationDropDown               matlab.ui.control.DropDown
        PanelOption_Crossover          matlab.ui.container.Panel
        RatioLabel                     matlab.ui.control.Label
        EditField_crossover            matlab.ui.control.NumericEditField
        CrossoverDropDownLabel         matlab.ui.control.Label
        CrossoverDropDown              matlab.ui.control.DropDown
        PanelOption_Selection          matlab.ui.container.Panel
        SizeLabel                      matlab.ui.control.Label
        SelectionDropDownLabel         matlab.ui.control.Label
        SelectionDropDown              matlab.ui.control.DropDown
        EditField_selection            matlab.ui.control.NumericEditField
        PanelOption_Population         matlab.ui.container.Panel
        PopulationSizeLabel            matlab.ui.control.Label
        InitRangeEditField_2           matlab.ui.control.NumericEditField
        InitPopRangeLabel              matlab.ui.control.Label
        InitRangeEditField             matlab.ui.control.NumericEditField
        PopSizeEditField               matlab.ui.control.NumericEditField
        PanelResults_Results           matlab.ui.container.Panel
        Label_ptotal_2                 matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_pg1_2                    matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Pg1Label                       matlab.ui.control.Label
        Pg2Label                       matlab.ui.control.Label
        PtotalLabel                    matlab.ui.control.Label
        Label_pg1                      matlab.ui.control.Label
        Label_pg2                      matlab.ui.control.Label
        Label_ptotal                   matlab.ui.control.Label
        FitnessFunctionPanel           matlab.ui.container.Panel
        Placeholder1_4                 matlab.ui.control.Label
        Placeholder1_3                 matlab.ui.control.Label
        Placeholder1_2                 matlab.ui.control.Label
        Placeholder1                   matlab.ui.control.Label
        PghLabel_2                     matlab.ui.control.Label
        C22                            matlab.ui.control.NumericEditField
        PgLabel_2                      matlab.ui.control.Label
        C21                            matlab.ui.control.NumericEditField
        CLabel_2                       matlab.ui.control.Label
        PghLabel                       matlab.ui.control.Label
        C12                            matlab.ui.control.NumericEditField
        PgLabel                        matlab.ui.control.Label
        C11                            matlab.ui.control.NumericEditField
        CLabel                         matlab.ui.control.Label
        Img_grid                       matlab.ui.control.Image
        RunButton                      matlab.ui.control.Button
        AlgorithmOptionsLabel          matlab.ui.control.Label
        ButtonGroup_Plot               matlab.ui.container.ButtonGroup
        rangeCheckBox                  matlab.ui.control.CheckBox
        expectationCheckBox            matlab.ui.control.CheckBox
        bestindivCheckBox              matlab.ui.control.CheckBox
        bestfCheckBox                  matlab.ui.control.CheckBox
        maxconstrCheckBox              matlab.ui.control.CheckBox
        selectionCheckBox              matlab.ui.control.CheckBox
        distanceCheckBox               matlab.ui.control.CheckBox
        scoresCheckBox                 matlab.ui.control.CheckBox
        genealogyCheckBox              matlab.ui.control.CheckBox
        stoppingCheckBox               matlab.ui.control.CheckBox
        scorediversityCheckBox         matlab.ui.control.CheckBox
        ConstraintsPanel               matlab.ui.container.Panel
        PenaltyFactorEditField         matlab.ui.control.NumericEditField
        PenaltyFactorEditFieldLabel    matlab.ui.control.Label
        InitialPenaltyEditField        matlab.ui.control.NumericEditField
        InitialPenaltyEditFieldLabel   matlab.ui.control.Label
        StoppingCriteriaPanel          matlab.ui.container.Panel
        MaxTimesEditField              matlab.ui.control.NumericEditField
        MaxTimesEditFieldLabel         matlab.ui.control.Label
        MaxGenerationsEditField        matlab.ui.control.NumericEditField
        MaxGenerationsEditFieldLabel   matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        NodalVoltageButtonGroup        matlab.ui.container.ButtonGroup
        X13                            matlab.ui.control.NumericEditField
        X23                            matlab.ui.control.NumericEditField
        X12                            matlab.ui.control.NumericEditField
        CheckBox_2                     matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Image2Clicked(app, event)
            %back to homepage
        end

        % Value changed function: DespachoEconmicoDropDown
        function DespachoEconmicoDropDownValueChanged(app, event)
            value = app.DespachoEconmicoDropDown.Value;
            switch value
                case 'Sem Perdas      (Ex2)'
                    app.Img_grid.Position = [30 350 400 300];
                    app.ProblemInformationPanel.Visible = 1;
                    app.info2bold.Text =    'Generator Output Limitation :';
                    app.info2text.Text =    'No Limits';
                    
                case 'Potência Máx.   (Ex3)'
                    app.Img_grid.Position = [30 350 400 300];
                    app.ProblemInformationPanel.Visible = 1;
                    app.info2bold.Text =    'Generator Output Limitation :';
                    app.info2text.Text =    'Generator 1 Output ≤  0.5 p.u.';
                    
                case 'Tensões nodais (Ex4)'
                    app.Img_grid.Position = [30 350 400 300];
                    app.ProblemInformationPanel.Visible = 1;
                    app.info2bold.Text =    'Generator Output Limitation :';
                    app.info2text.Text =   {'X12 = j0.3';
                                            'X13 = j0.1';
                                            'X23 = j0.1'};
                    
                case 'Customizar Problema'
                    app.Img_grid.Position = [30,500,200,150];
                    app.ProblemInformationPanel.Visible = 0;

            end
        end

        % Callback function
        function RunButtonPushed(app, event)
        % ϴ   Θ 
        % Get Plots            
            plots = [];
            all_options = get(app.ButtonGroup_Plot,'Children')
            for i = 1:numel(all_options)
                if all_options(i).Value == 1
                    plots = [plots ("@gaplot" + all_options(i).Text)]
                end
            end
            save('cachefile.mat', 'plots','-append'); 
            
            % Constantes 
            pg1     = str2double(app.C11.Value);
            pg1_2   = str2double(app.C12.Value);
            pg2     = str2double(app.C21.Value);
            pg2_2   = str2double(app.C22.Value);
            
            p3      = str2double(app.EditField_P3.Value);
            
            %save cachefile pg1 pg1_2 pg2 pg2_2 p3;
            save('cachefile.mat', 'pg1','pg1_2','pg2','pg2_2','p3','-append'); 
            
            %chama a função gacusto
            gacusto();
            load cachefile x1 x2 x3 x4;
            
            % atualiza os resultados
            app.Label_pg1.Text     = x1;
            app.Label_pg2.Text     = x2;
            app.Label_vf.Text      = x3;
            app.Label_ptotal.Text  = x4;
        
        end

        % Callback function
        function SelectionDropDownValueChanged(app, event)
            value = app.SelectionDropDown.Value
            
            if value=="selectiontournament"
                app.EditField_selection.Visible = 1;
            else
                app.EditField_selection.Visible = 0;            
            end
        end

        % Callback function
        function CrossoverDropDownValueChanged(app, event)
            value = app.CrossoverDropDown.Value;
            if value=="crossoverheuristic"
                app.EditField_crossover.Visible = 1;
            else
                app.EditField_crossover.Visible = 0;            
            end
        end

        % Callback function
        function MutationDropDownValueChanged(app, event)
            value = app.MutationDropDown.Value;
            if value=="mutationgaussian"
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 1;
                app.Mutation_TextField2.Visible = 1;
                
                app.Mutation_TextField1.Text = "Scale";
                app.Mutation_EditField1.Value = 1;
                app.Mutation_TextField2.Text = "Shrink";
                app.Mutation_EditField2.Value = 1;
                
            elseif value=="mutationuniform"
                    
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
                
                app.Mutation_TextField1.Text = "Rate";
                app.Mutation_EditField1.Value = 0.01;
            else
                app.Mutation_EditField1.Visible = 0;
                app.Mutation_TextField1.Visible = 0;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NodalVoltageButtonGroup = uibuttongroup(app.UIFigure);
            app.NodalVoltageButtonGroup.Title = 'Nodal Voltage';
            app.CheckBox_2 = uicheckbox(app.NodalVoltageButtonGroup);
            app.CheckBox_2.Text = '';
            app.X12 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.X12.Value = 0.3;
            app.X23 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.X23.Value = 0.1;
            app.X13 = uieditfield(app.NodalVoltageButtonGroup, 'numeric');
            app.X13.Value = 0.1;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StoppingCriteriaPanel = uipanel(app.UIFigure);
            app.StoppingCriteriaPanel.Title = 'Stopping Criteria';
            app.MaxGenerationsEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerationsEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.MaxGenerationsEditField.Value = 200;
            app.MaxTimesEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxTimesEditFieldLabel.Text = 'Max Time (s)';
            app.MaxTimesEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.ConstraintsPanel = uipanel(app.UIFigure);
            app.ConstraintsPanel.Title = 'Constraints';
            app.InitialPenaltyEditFieldLabel = uilabel(app.ConstraintsPanel);
            app.InitialPenaltyEditFieldLabel.Text = 'Initial Penalty ';
            app.InitialPenaltyEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.InitialPenaltyEditField.Limits = [1 Inf];
            app.InitialPenaltyEditField.Value = 10;
            app.PenaltyFactorEditFieldLabel = uilabel(app.ConstraintsPanel);
            app.PenaltyFactorEditFieldLabel.Text = 'Penalty Factor ';
            app.PenaltyFactorEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.PenaltyFactorEditField.Limits = [1 Inf];
            app.PenaltyFactorEditField.Value = 100;
            app.ButtonGroup_Plot = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Plot.Title = 'Plot Options';
            app.scorediversityCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scorediversityCheckBox.Text = 'scorediversity';
            app.stoppingCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.stoppingCheckBox.Text = 'stopping';
            app.stoppingCheckBox.Value = true;
            app.genealogyCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.genealogyCheckBox.Text = 'genealogy';
            app.scoresCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scoresCheckBox.Text = 'scores';
            app.distanceCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.distanceCheckBox.Text = 'distance';
            app.selectionCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.selectionCheckBox.Text = 'selection';
            app.maxconstrCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.maxconstrCheckBox.Text = 'maxconstr';
            app.bestfCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestfCheckBox.Text = 'bestf';
            app.bestfCheckBox.Value = true;
            app.bestindivCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestindivCheckBox.Text = 'bestindiv';
            app.bestindivCheckBox.Value = true;
            app.expectationCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.expectationCheckBox.Text = 'expectation';
            app.expectationCheckBox.Value = true;
            app.rangeCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.rangeCheckBox.Text = 'range';
            app.AlgorithmOptionsLabel = uilabel(app.UIFigure);
            app.AlgorithmOptionsLabel.Text = 'Algorithm Options';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.Text = 'Run';
            app.Img_grid = uiimage(app.UIFigure);
            app.Img_grid.ImageSource = 'grid-removebg.png';
            app.FitnessFunctionPanel = uipanel(app.UIFigure);
            app.FitnessFunctionPanel.Title = 'Fitness Function';
            app.CLabel = uilabel(app.FitnessFunctionPanel);
            app.CLabel.Text = 'C  =';
            app.C11 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C11.Value = 0.3;
            app.PgLabel = uilabel(app.FitnessFunctionPanel);
            app.PgLabel.Text = 'Pg   +';
            app.C12 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C12.Value = 0.01;
            app.PghLabel = uilabel(app.FitnessFunctionPanel);
            app.PghLabel.Text = 'Pg   (€ / h)';
            app.CLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.CLabel_2.Text = 'C  =';
            app.C21 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C21.Value = 0.2;
            app.PgLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PgLabel_2.Text = 'Pg   +';
            app.C22 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C22.Value = 0.2;
            app.PghLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PghLabel_2.Text = 'Pg   (€ / h)';
            app.Placeholder1 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1.Text = '2';
            app.Placeholder1_2 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_2.Text = '2';
            app.Placeholder1_3 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_3.Text = '2                        2                          2';
            app.Placeholder1_4 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_4.Text = '1                        1                          1';
            app.PanelResults_Results = uipanel(app.UIFigure);
            app.PanelResults_Results.Title = 'Problem Results';
            app.Label_ptotal = uilabel(app.PanelResults_Results);
            app.Label_ptotal.Text = '';
            app.Label_pg2 = uilabel(app.PanelResults_Results);
            app.Label_pg2.Text = '';
            app.Label_pg1 = uilabel(app.PanelResults_Results);
            app.Label_pg1.Text = '';
            app.PtotalLabel = uilabel(app.PanelResults_Results);
            app.PtotalLabel.Text = 'Ptotal:';
            app.Pg2Label = uilabel(app.PanelResults_Results);
            app.Pg2Label.Text = 'Pg2:';
            app.Pg1Label = uilabel(app.PanelResults_Results);
            app.Pg1Label.Text = 'Pg1:';
            app.Label_2 = uilabel(app.PanelResults_Results);
            app.Label_2.Text = 'Θ2';
            app.Label_pg1_2 = uilabel(app.PanelResults_Results);
            app.Label_pg1_2.Text = '';
            app.Label_3 = uilabel(app.PanelResults_Results);
            app.Label_3.Text = 'Θ3';
            app.Label_ptotal_2 = uilabel(app.PanelResults_Results);
            app.Label_ptotal_2.Text = '';
            app.PanelOption_Population = uipanel(app.UIFigure);
            app.PanelOption_Population.Title = 'Population';
            app.PopSizeEditField = uieditfield(app.PanelOption_Population, 'numeric');
            app.PopSizeEditField.Limits = [1 Inf];
            app.PopSizeEditField.RoundFractionalValues = 'on';
            app.PopSizeEditField.Value = 50;
            app.InitRangeEditField = uieditfield(app.PanelOption_Population, 'numeric');
            app.InitRangeEditField.Value = -10;
            app.InitPopRangeLabel = uilabel(app.PanelOption_Population);
            app.InitPopRangeLabel.Text = 'Init. Pop. Range';
            app.InitRangeEditField_2 = uieditfield(app.PanelOption_Population, 'numeric');
            app.InitRangeEditField_2.Value = 10;
            app.PopulationSizeLabel = uilabel(app.PanelOption_Population);
            app.PopulationSizeLabel.Text = 'Population Size';
            app.PanelOption_Selection = uipanel(app.UIFigure);
            app.EditField_selection = uieditfield(app.PanelOption_Selection, 'numeric');
            app.EditField_selection.Visible = 'off';
            app.EditField_selection.Value = 4;
            app.SelectionDropDown = uidropdown(app.PanelOption_Selection);
            app.SelectionDropDown.Items = {'selectionstochunif', 'selectionremainder', 'selectionuniform', 'selectionroulette', 'selectiontournament'};
            app.SelectionDropDown.Value = 'selectionstochunif';
            app.SelectionDropDownLabel = uilabel(app.PanelOption_Selection);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.SizeLabel = uilabel(app.PanelOption_Selection);
            app.SizeLabel.Text = 'Size';
            app.PanelOption_Crossover = uipanel(app.UIFigure);
            app.CrossoverDropDown = uidropdown(app.PanelOption_Crossover);
            app.CrossoverDropDown.Items = {'crossoverscattered', 'crossoversinglepoint', 'crossovertwopoint', 'crossoverintermediate', 'crossoverarithmetic', 'crossoverheuristic'};
            app.CrossoverDropDown.Value = 'crossoverscattered';
            app.CrossoverDropDownLabel = uilabel(app.PanelOption_Crossover);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.EditField_crossover = uieditfield(app.PanelOption_Crossover, 'numeric');
            app.EditField_crossover.Visible = 'off';
            app.EditField_crossover.Value = 1.2;
            app.RatioLabel = uilabel(app.PanelOption_Crossover);
            app.RatioLabel.Text = 'Ratio';
            app.PanelOption_Mutation = uipanel(app.UIFigure);
            app.MutationDropDown = uidropdown(app.PanelOption_Mutation);
            app.MutationDropDown.Items = {'mutationgaussian', 'mutationuniform', 'mutationadaptfeasible', 'mutationpower', 'mutationpositivebasis'};
            app.MutationDropDown.Value = 'mutationgaussian';
            app.MutationDropDownLabel = uilabel(app.PanelOption_Mutation);
            app.MutationDropDownLabel.Text = 'Mutation';
            app.Mutation_TextField1 = uilabel(app.PanelOption_Mutation);
            app.Mutation_TextField1.Text = 'Scale';
            app.Mutation_TextField2 = uilabel(app.PanelOption_Mutation);
            app.Mutation_TextField2.Text = 'Shrink';
            app.Mutation_EditField2 = uieditfield(app.PanelOption_Mutation, 'numeric');
            app.Mutation_EditField2.Value = 1;
            app.Mutation_EditField1 = uieditfield(app.PanelOption_Mutation, 'numeric');
            app.Mutation_EditField1.Value = 1;
            app.a_Logo = uiimage(app.UIFigure);
            app.a_Logo.ImageSource = 'isep.png';
            app.Panel = uipanel(app.UIFigure);
            app.ReproductionLabel = uilabel(app.Panel);
            app.ReproductionLabel.Text = 'Reproduction';
            app.EliteCountEditField = uieditfield(app.Panel, 'numeric');
            app.EliteCountEditField.Limits = [1 Inf];
            app.EliteCountEditField.RoundFractionalValues = 'on';
            app.EliteCountEditField.Value = 2;
            app.EliteCountLabel = uilabel(app.Panel);
            app.EliteCountLabel.Text = 'Elite Count';
            app.CrossoverFractionLabel = uilabel(app.Panel);
            app.CrossoverFractionLabel.Text = 'Crossover Fraction';
            app.MaxGenEditField = uieditfield(app.Panel, 'numeric');
            app.MaxGenEditField.LowerLimitInclusive = 'off';
            app.MaxGenEditField.UpperLimitInclusive = 'off';
            app.MaxGenEditField.Limits = [0 1];
            app.MaxGenEditField.Value = 0.8;
            app.ProblemInformationPanel = uipanel(app.UIFigure);
            app.ProblemInformationPanel.Title = 'Problem Information';
            app.ProblemInformationPanel.Visible = 'off';
            app.info1img = uiimage(app.ProblemInformationPanel);
            app.info1img.ImageSource = 'img_func.png';
            app.info1bold = uilabel(app.ProblemInformationPanel);
            app.info1bold.Text = 'Production Cost Function:';
            app.info2bold = uilabel(app.ProblemInformationPanel);
            app.info2bold.Text = 'Generator Output Limitation :';
            app.info2text = uilabel(app.ProblemInformationPanel);
            app.info2text.Text = {'X12'; 'X13'; 'X23'};
            app.PanelResults_AlgorithmStatistics = uipanel(app.UIFigure);
            app.PanelResults_AlgorithmStatistics.Title = 'Algorithm Statistics';
            app.Label_vf = uilabel(app.PanelResults_AlgorithmStatistics);
            app.Label_vf.Text = '';
            app.FitnessValueLabel = uilabel(app.PanelResults_AlgorithmStatistics);
            app.FitnessValueLabel.Text = 'Fitness Value:';
            app.TimeLabel = uilabel(app.PanelResults_AlgorithmStatistics);
            app.TimeLabel.Text = 'Time:';
            app.Label_vf_2 = uilabel(app.PanelResults_AlgorithmStatistics);
            app.Label_vf_2.Text = '';
            app.DespachoEconmicoDropDownLabel = uilabel(app.UIFigure);
            app.DespachoEconmicoDropDownLabel.Text = 'Despacho Económico';
            app.DespachoEconmicoDropDown = uidropdown(app.UIFigure);
            app.DespachoEconmicoDropDown.Items = {'Sem Perdas      (Ex2)', 'Potência Máx.   (Ex3)', 'Tensões nodais (Ex4)', 'Customizar Problema'};
            app.DespachoEconmicoDropDown.ValueChangedFcn = createCallbackFcn(app, @DespachoEconmicoDropDownValueChanged, true);
            app.DespachoEconmicoDropDown.Value = 'Sem Perdas      (Ex2)';
            app.ConstrainMaxOutputButtonGroup = uibuttongroup(app.UIFigure);
            app.ConstrainMaxOutputButtonGroup.Title = 'Constrain Max Output';
            app.Pg1 = uiradiobutton(app.ConstrainMaxOutputButtonGroup);
            app.Pg1.Text = 'Pg    ≤';
            app.Pg1.Value = true;
            app.Pg2 = uiradiobutton(app.ConstrainMaxOutputButtonGroup);
            app.Pg2.Text = 'Pg    ≤';
            app.PgEditField = uieditfield(app.ConstrainMaxOutputButtonGroup, 'numeric');
            app.EditField_2 = uieditfield(app.ConstrainMaxOutputButtonGroup, 'numeric');
            app.CheckBox = uicheckbox(app.ConstrainMaxOutputButtonGroup);
            app.CheckBox.Text = '';
            app.Placeholder1_9 = uilabel(app.ConstrainMaxOutputButtonGroup);
            app.Placeholder1_9.Text = {'1'; ''; '2'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.teta3teta2x12x23x2311teta2teta3x13x23x23Label = uilabel(app.UIFigure);
            app.teta3teta2x12x23x2311teta2teta3x13x23x23Label.Text = {'/       /  1     1  \   teta3 \'; '| teta2 | --- + --- | - ----- |'; '|       \ x12   x23 /    x23  |'; '|                             |'; '|       /  1     1  \   teta2 |'; '| teta3 | --- + --- | - ----- |'; '\       \ x13   x23 /    x23  /'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = excustom

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end