classdef ex2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ProblemInformationPanel         matlab.ui.container.Panel
        NoLimitsLabel                   matlab.ui.control.Label
        GeneratorOutputLimitationLabel  matlab.ui.control.Label
        ProductionCostFunctionLabel     matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Label_2                         matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        MaxGenEditField                 matlab.ui.control.NumericEditField
        CrossoverFractionLabel          matlab.ui.control.Label
        EliteCountLabel                 matlab.ui.control.Label
        EliteCountEditField             matlab.ui.control.NumericEditField
        ReproductionLabel               matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        DespachoEconmicoDropDown        matlab.ui.control.DropDown
        DespachoEconmicoDropDownLabel   matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        Mutation_EditField1             matlab.ui.control.NumericEditField
        Mutation_EditField2             matlab.ui.control.NumericEditField
        Mutation_TextField2             matlab.ui.control.Label
        Mutation_TextField1             matlab.ui.control.Label
        MutationDropDownLabel           matlab.ui.control.Label
        MutationDropDown                matlab.ui.control.DropDown
        Panel_2                         matlab.ui.container.Panel
        RatioLabel                      matlab.ui.control.Label
        EditField_crossover             matlab.ui.control.NumericEditField
        CrossoverDropDownLabel          matlab.ui.control.Label
        CrossoverDropDown               matlab.ui.control.DropDown
        Panel                           matlab.ui.container.Panel
        SizeLabel                       matlab.ui.control.Label
        SelectionDropDownLabel          matlab.ui.control.Label
        SelectionDropDown               matlab.ui.control.DropDown
        EditField_selection             matlab.ui.control.NumericEditField
        PopulationPanel                 matlab.ui.container.Panel
        PopulationSizeLabel             matlab.ui.control.Label
        InitRangeEditField_2            matlab.ui.control.NumericEditField
        InitPopRangeLabel               matlab.ui.control.Label
        InitRangeEditField              matlab.ui.control.NumericEditField
        PopSizeEditField                matlab.ui.control.NumericEditField
        ResultsPanel                    matlab.ui.container.Panel
        FitnessValueLabel               matlab.ui.control.Label
        Pg1Label                        matlab.ui.control.Label
        Pg2Label                        matlab.ui.control.Label
        PtotalLabel                     matlab.ui.control.Label
        Label_vf                        matlab.ui.control.Label
        Label_pg1                       matlab.ui.control.Label
        Label_pg2                       matlab.ui.control.Label
        Label_ptotal                    matlab.ui.control.Label
        FitnessFunctionPanel            matlab.ui.container.Panel
        Placeholder1_4                  matlab.ui.control.Label
        Placeholder1_3                  matlab.ui.control.Label
        Placeholder1_2                  matlab.ui.control.Label
        Placeholder1                    matlab.ui.control.Label
        PghLabel_2                      matlab.ui.control.Label
        C22                             matlab.ui.control.NumericEditField
        PgLabel_2                       matlab.ui.control.Label
        C21                             matlab.ui.control.NumericEditField
        CLabel_2                        matlab.ui.control.Label
        PghLabel                        matlab.ui.control.Label
        C12                             matlab.ui.control.NumericEditField
        PgLabel                         matlab.ui.control.Label
        C11                             matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        RunButton                       matlab.ui.control.Button
        AlgorithmOptionsLabel           matlab.ui.control.Label
        ButtonGroup_Plot                matlab.ui.container.ButtonGroup
        rangeCheckBox                   matlab.ui.control.CheckBox
        expectationCheckBox             matlab.ui.control.CheckBox
        bestindivCheckBox               matlab.ui.control.CheckBox
        bestfCheckBox                   matlab.ui.control.CheckBox
        maxconstrCheckBox               matlab.ui.control.CheckBox
        selectionCheckBox               matlab.ui.control.CheckBox
        distanceCheckBox                matlab.ui.control.CheckBox
        scoresCheckBox                  matlab.ui.control.CheckBox
        genealogyCheckBox               matlab.ui.control.CheckBox
        stoppingCheckBox                matlab.ui.control.CheckBox
        scorediversityCheckBox          matlab.ui.control.CheckBox
        ConstraintsPanel                matlab.ui.container.Panel
        PenaltyFactorEditField          matlab.ui.control.NumericEditField
        PenaltyFactorEditFieldLabel     matlab.ui.control.Label
        InitialPenaltyEditField         matlab.ui.control.NumericEditField
        InitialPenaltyEditFieldLabel    matlab.ui.control.Label
        StoppingCriteriaPanel           matlab.ui.container.Panel
        MaxTimesEditField               matlab.ui.control.NumericEditField
        MaxTimesEditFieldLabel          matlab.ui.control.Label
        MaxGenerationsEditField         matlab.ui.control.NumericEditField
        MaxGenerationsEditFieldLabel    matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
        % Get Plots            
            plots = [];
            all_options = get(app.ButtonGroup_Plot,'Children')
            for i = 1:numel(all_options)
                if all_options(i).Value == 1
                    plots = [plots ("@gaplot" + all_options(i).Text)]
                end
            end
            save('cachefile.mat', 'plots','-append'); 
            
            % Constantes 
            pg1     = str2double(app.C11.Value);
            pg1_2   = str2double(app.C12.Value);
            pg2     = str2double(app.C21.Value);
            pg2_2   = str2double(app.C22.Value);
            
            p3      = str2double(app.EditField_P3.Value);
            
            %save cachefile pg1 pg1_2 pg2 pg2_2 p3;
            save('cachefile.mat', 'pg1','pg1_2','pg2','pg2_2','p3','-append'); 
            
            %chama a função gacusto
            gacusto();
            load cachefile x1 x2 x3 x4;
            
            % atualiza os resultados
            app.Label_pg1.Text     = x1;
            app.Label_pg2.Text     = x2;
            app.Label_vf.Text      = x3;
            app.Label_ptotal.Text  = x4;
        
        end

        % Value changed function: SelectionDropDown
        function SelectionDropDownValueChanged(app, event)
            value = app.SelectionDropDown.Value
            
            if value=="selectiontournament"
                app.EditField_selection.Visible = 1;
            else
                app.EditField_selection.Visible = 0;            
            end
        end

        % Value changed function: CrossoverDropDown
        function CrossoverDropDownValueChanged(app, event)
            value = app.CrossoverDropDown.Value;
            if value=="crossoverheuristic"
                app.EditField_crossover.Visible = 1;
            else
                app.EditField_crossover.Visible = 0;            
            end
        end

        % Value changed function: MutationDropDown
        function MutationDropDownValueChanged(app, event)
            value = app.MutationDropDown.Value;
            if value=="mutationgaussian"
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 1;
                app.Mutation_TextField2.Visible = 1;
                
                app.Mutation_TextField1.Text = "Scale";
                app.Mutation_EditField1.Value = 1;
                app.Mutation_TextField2.Text = "Shrink";
                app.Mutation_EditField2.Value = 1;
                
            elseif value=="mutationuniform"
                    
                app.Mutation_EditField1.Visible = 1;
                app.Mutation_TextField1.Visible = 1;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
                
                app.Mutation_TextField1.Text = "Rate";
                app.Mutation_EditField1.Value = 0.01;
            else
                app.Mutation_EditField1.Visible = 0;
                app.Mutation_TextField1.Visible = 0;
                app.Mutation_EditField2.Visible = 0;
                app.Mutation_TextField2.Visible = 0;
            end
        end

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            %back to homepage
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.StoppingCriteriaPanel = uipanel(app.UIFigure);
            app.StoppingCriteriaPanel.Title = 'Stopping Criteria';
            app.MaxGenerationsEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxGenerationsEditFieldLabel.Text = 'Max Generations';
            app.MaxGenerationsEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.MaxGenerationsEditField.Value = 200;
            app.MaxTimesEditFieldLabel = uilabel(app.StoppingCriteriaPanel);
            app.MaxTimesEditFieldLabel.Text = 'Max Time (s)';
            app.MaxTimesEditField = uieditfield(app.StoppingCriteriaPanel, 'numeric');
            app.ConstraintsPanel = uipanel(app.UIFigure);
            app.ConstraintsPanel.Title = 'Constraints';
            app.InitialPenaltyEditFieldLabel = uilabel(app.ConstraintsPanel);
            app.InitialPenaltyEditFieldLabel.Text = 'Initial Penalty ';
            app.InitialPenaltyEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.InitialPenaltyEditField.Limits = [1 Inf];
            app.InitialPenaltyEditField.Value = 10;
            app.PenaltyFactorEditFieldLabel = uilabel(app.ConstraintsPanel);
            app.PenaltyFactorEditFieldLabel.Text = 'Penalty Factor ';
            app.PenaltyFactorEditField = uieditfield(app.ConstraintsPanel, 'numeric');
            app.PenaltyFactorEditField.Limits = [1 Inf];
            app.PenaltyFactorEditField.Value = 100;
            app.ButtonGroup_Plot = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Plot.Title = 'Plot Options';
            app.scorediversityCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scorediversityCheckBox.Text = 'scorediversity';
            app.stoppingCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.stoppingCheckBox.Text = 'stopping';
            app.stoppingCheckBox.Value = true;
            app.genealogyCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.genealogyCheckBox.Text = 'genealogy';
            app.scoresCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.scoresCheckBox.Text = 'scores';
            app.distanceCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.distanceCheckBox.Text = 'distance';
            app.selectionCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.selectionCheckBox.Text = 'selection';
            app.maxconstrCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.maxconstrCheckBox.Text = 'maxconstr';
            app.bestfCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestfCheckBox.Text = 'bestf';
            app.bestfCheckBox.Value = true;
            app.bestindivCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.bestindivCheckBox.Text = 'bestindiv';
            app.bestindivCheckBox.Value = true;
            app.expectationCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.expectationCheckBox.Text = 'expectation';
            app.expectationCheckBox.Value = true;
            app.rangeCheckBox = uicheckbox(app.ButtonGroup_Plot);
            app.rangeCheckBox.Text = 'range';
            app.AlgorithmOptionsLabel = uilabel(app.UIFigure);
            app.AlgorithmOptionsLabel.Text = 'Algorithm Options';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'grid-removebg.png';
            app.FitnessFunctionPanel = uipanel(app.UIFigure);
            app.FitnessFunctionPanel.Title = 'Fitness Function';
            app.CLabel = uilabel(app.FitnessFunctionPanel);
            app.CLabel.Text = 'C  =';
            app.C11 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C11.Value = 0.3;
            app.PgLabel = uilabel(app.FitnessFunctionPanel);
            app.PgLabel.Text = 'Pg   +';
            app.C12 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C12.Value = 0.01;
            app.PghLabel = uilabel(app.FitnessFunctionPanel);
            app.PghLabel.Text = 'Pg   (€ / h)';
            app.CLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.CLabel_2.Text = 'C  =';
            app.C21 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C21.Value = 0.2;
            app.PgLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PgLabel_2.Text = 'Pg   +';
            app.C22 = uieditfield(app.FitnessFunctionPanel, 'numeric');
            app.C22.Value = 0.2;
            app.PghLabel_2 = uilabel(app.FitnessFunctionPanel);
            app.PghLabel_2.Text = 'Pg   (€ / h)';
            app.Placeholder1 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1.Text = '2';
            app.Placeholder1_2 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_2.Text = '2';
            app.Placeholder1_3 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_3.Text = '2                        2                          2';
            app.Placeholder1_4 = uilabel(app.FitnessFunctionPanel);
            app.Placeholder1_4.Text = '1                        1                          1';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.Label_ptotal = uilabel(app.ResultsPanel);
            app.Label_ptotal.Text = '';
            app.Label_pg2 = uilabel(app.ResultsPanel);
            app.Label_pg2.Text = '';
            app.Label_pg1 = uilabel(app.ResultsPanel);
            app.Label_pg1.Text = '';
            app.Label_vf = uilabel(app.ResultsPanel);
            app.Label_vf.Text = '';
            app.PtotalLabel = uilabel(app.ResultsPanel);
            app.PtotalLabel.Text = 'Ptotal:';
            app.Pg2Label = uilabel(app.ResultsPanel);
            app.Pg2Label.Text = 'Pg2:';
            app.Pg1Label = uilabel(app.ResultsPanel);
            app.Pg1Label.Text = 'Pg1:';
            app.FitnessValueLabel = uilabel(app.ResultsPanel);
            app.FitnessValueLabel.Text = 'Fitness Value:';
            app.PopulationPanel = uipanel(app.UIFigure);
            app.PopulationPanel.Title = 'Population';
            app.PopSizeEditField = uieditfield(app.PopulationPanel, 'numeric');
            app.PopSizeEditField.Limits = [1 Inf];
            app.PopSizeEditField.RoundFractionalValues = 'on';
            app.PopSizeEditField.Value = 50;
            app.InitRangeEditField = uieditfield(app.PopulationPanel, 'numeric');
            app.InitRangeEditField.Value = -10;
            app.InitPopRangeLabel = uilabel(app.PopulationPanel);
            app.InitPopRangeLabel.Text = 'Init. Pop. Range';
            app.InitRangeEditField_2 = uieditfield(app.PopulationPanel, 'numeric');
            app.InitRangeEditField_2.Value = 10;
            app.PopulationSizeLabel = uilabel(app.PopulationPanel);
            app.PopulationSizeLabel.Text = 'Population Size';
            app.Panel = uipanel(app.UIFigure);
            app.EditField_selection = uieditfield(app.Panel, 'numeric');
            app.EditField_selection.Visible = 'off';
            app.EditField_selection.Value = 4;
            app.SelectionDropDown = uidropdown(app.Panel);
            app.SelectionDropDown.Items = {'selectionstochunif', 'selectionremainder', 'selectionuniform', 'selectionroulette', 'selectiontournament'};
            app.SelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectionDropDownValueChanged, true);
            app.SelectionDropDown.Value = 'selectionstochunif';
            app.SelectionDropDownLabel = uilabel(app.Panel);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.SizeLabel = uilabel(app.Panel);
            app.SizeLabel.Text = 'Size';
            app.Panel_2 = uipanel(app.UIFigure);
            app.CrossoverDropDown = uidropdown(app.Panel_2);
            app.CrossoverDropDown.Items = {'crossoverscattered', 'crossoversinglepoint', 'crossovertwopoint', 'crossoverintermediate', 'crossoverarithmetic', 'crossoverheuristic'};
            app.CrossoverDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossoverDropDownValueChanged, true);
            app.CrossoverDropDown.Value = 'crossoverscattered';
            app.CrossoverDropDownLabel = uilabel(app.Panel_2);
            app.CrossoverDropDownLabel.Text = 'Crossover';
            app.EditField_crossover = uieditfield(app.Panel_2, 'numeric');
            app.EditField_crossover.Visible = 'off';
            app.EditField_crossover.Value = 1.2;
            app.RatioLabel = uilabel(app.Panel_2);
            app.RatioLabel.Text = 'Ratio';
            app.Panel_3 = uipanel(app.UIFigure);
            app.MutationDropDown = uidropdown(app.Panel_3);
            app.MutationDropDown.Items = {'mutationgaussian', 'mutationuniform', 'mutationadaptfeasible', 'mutationpower', 'mutationpositivebasis'};
            app.MutationDropDown.ValueChangedFcn = createCallbackFcn(app, @MutationDropDownValueChanged, true);
            app.MutationDropDown.Value = 'mutationgaussian';
            app.MutationDropDownLabel = uilabel(app.Panel_3);
            app.MutationDropDownLabel.Text = 'Mutation';
            app.Mutation_TextField1 = uilabel(app.Panel_3);
            app.Mutation_TextField1.Text = 'Scale';
            app.Mutation_TextField2 = uilabel(app.Panel_3);
            app.Mutation_TextField2.Text = 'Shrink';
            app.Mutation_EditField2 = uieditfield(app.Panel_3, 'numeric');
            app.Mutation_EditField2.Value = 1;
            app.Mutation_EditField1 = uieditfield(app.Panel_3, 'numeric');
            app.Mutation_EditField1.Value = 1;
            app.DespachoEconmicoDropDownLabel = uilabel(app.UIFigure);
            app.DespachoEconmicoDropDownLabel.Text = 'Despacho Económico';
            app.DespachoEconmicoDropDown = uidropdown(app.UIFigure);
            app.DespachoEconmicoDropDown.Items = {'Sem Perdas      (Ex2)', 'Potência Máx.   (Ex3)', 'Tensões nodais (Ex4)', 'Customizar Problema'};
            app.DespachoEconmicoDropDown.Value = 'Sem Perdas      (Ex2)';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.Image2.ImageSource = 'isep.png';
            app.Panel_4 = uipanel(app.UIFigure);
            app.ReproductionLabel = uilabel(app.Panel_4);
            app.ReproductionLabel.Text = 'Reproduction';
            app.EliteCountEditField = uieditfield(app.Panel_4, 'numeric');
            app.EliteCountEditField.Limits = [1 Inf];
            app.EliteCountEditField.RoundFractionalValues = 'on';
            app.EliteCountEditField.Value = 2;
            app.EliteCountLabel = uilabel(app.Panel_4);
            app.EliteCountLabel.Text = 'Elite Count';
            app.CrossoverFractionLabel = uilabel(app.Panel_4);
            app.CrossoverFractionLabel.Text = 'Crossover Fraction';
            app.MaxGenEditField = uieditfield(app.Panel_4, 'numeric');
            app.MaxGenEditField.LowerLimitInclusive = 'off';
            app.MaxGenEditField.UpperLimitInclusive = 'off';
            app.MaxGenEditField.Limits = [0 1];
            app.MaxGenEditField.Value = 0.8;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ProblemInformationPanel = uipanel(app.UIFigure);
            app.ProblemInformationPanel.Title = 'Problem Information';
            app.Image3 = uiimage(app.ProblemInformationPanel);
            app.Image3.ImageSource = 'img_func.png';
            app.ProductionCostFunctionLabel = uilabel(app.ProblemInformationPanel);
            app.ProductionCostFunctionLabel.Text = 'Production Cost Function:';
            app.GeneratorOutputLimitationLabel = uilabel(app.ProblemInformationPanel);
            app.GeneratorOutputLimitationLabel.Text = 'Generator Output Limitation :';
            app.NoLimitsLabel = uilabel(app.ProblemInformationPanel);
            app.NoLimitsLabel.Text = 'No Limits';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end